classdef robotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        UninitializerobotButton  matlab.ui.control.Button
        ESTOPmagicianButton      matlab.ui.control.Button
        InitialiseRobotButton    matlab.ui.control.Button
        Joint1Slider             matlab.ui.control.Slider
        Joint1SliderLabel        matlab.ui.control.Label
        Joint5Slider             matlab.ui.control.Slider
        Joint5SliderLabel        matlab.ui.control.Label
        Joint4Slider             matlab.ui.control.Slider
        Joint4SliderLabel        matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dobotMagician; % The object which holds the functions for manipulating the dobot magician, as well as the model itself
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            plot(app.UIAxes,twolink.plot([0, value]))
        end

        % Button pushed function: InitialiseRobotButton
        function InitialiseRobotButtonPushed(app, event)
            app.InitialiseRobotButton.Visible = false;
            
            app.dobotMagician = magician();
            app.UninitializerobotButton.Visible = true;
            app.ESTOPmagicianButton.Visible = true;
            
            % creating the slider lengths based on the Joint limits
            qlims = rad2deg(app.dobotMagician.robot.model.qlim);
            q = rad2deg(app.dobotMagician.armQ);
            
            app.Joint1Slider.Limits = qlims(1,:);
            app.Joint2Slider.Limits = qlims(2,:);
            app.Joint3Slider.Limits = qlims(3,:);
            app.Joint4Slider.Limits = qlims(4,:);
            app.Joint5Slider.Limits = qlims(5,:);

            app.Joint1Slider.Value = q(1);
            app.Joint2Slider.Value = q(2);
            app.Joint3Slider.Value = q(3);
            app.Joint4Slider.Value = q(4);
            app.Joint5Slider.Value = q(5);

            app.Joint1Slider.Visible = true;
            app.Joint2Slider.Visible = true;
            app.Joint3Slider.Visible = true;
            app.Joint4Slider.Visible = true;
            app.Joint5Slider.Visible = true;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {335, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Dobot Magician';
            app.Joint2SliderLabel = uilabel(app.LeftPanel);
            app.Joint2SliderLabel.Visible = 'off';
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.LeftPanel);
            app.Joint2Slider.Visible = 'off';
            app.Joint3SliderLabel = uilabel(app.LeftPanel);
            app.Joint3SliderLabel.Visible = 'off';
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.LeftPanel);
            app.Joint3Slider.Visible = 'off';
            app.Joint4SliderLabel = uilabel(app.LeftPanel);
            app.Joint4SliderLabel.Visible = 'off';
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.LeftPanel);
            app.Joint4Slider.Visible = 'off';
            app.Joint5SliderLabel = uilabel(app.LeftPanel);
            app.Joint5SliderLabel.Visible = 'off';
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.LeftPanel);
            app.Joint5Slider.Visible = 'off';
            app.Joint1SliderLabel = uilabel(app.LeftPanel);
            app.Joint1SliderLabel.Visible = 'off';
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.LeftPanel);
            app.Joint1Slider.Visible = 'off';
            app.InitialiseRobotButton = uibutton(app.LeftPanel, 'push');
            app.InitialiseRobotButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseRobotButtonPushed, true);
            app.InitialiseRobotButton.Text = 'Initialise Robot';
            app.ESTOPmagicianButton = uibutton(app.LeftPanel, 'push');
            app.ESTOPmagicianButton.Visible = 'off';
            app.ESTOPmagicianButton.Text = 'ESTOP (magician)';
            app.UninitializerobotButton = uibutton(app.LeftPanel, 'push');
            app.UninitializerobotButton.Visible = 'off';
            app.UninitializerobotButton.Text = 'Uninitialize robot';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end